classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TimeShiftSliderLabel          matlab.ui.control.Label
        TimeShiftSlider               matlab.ui.control.Slider
        onsetSlider                   matlab.ui.control.Slider
        LockyscaleCheckBox            matlab.ui.control.CheckBox
        SaveButton                    matlab.ui.control.Button
        onsetLabel                    matlab.ui.control.Label
        RstartSlider                  matlab.ui.control.Slider
        RendSlider                    matlab.ui.control.Slider
        ClearButton                   matlab.ui.control.Button
        infectedStatBox               matlab.ui.control.Label
        n0Slider                      matlab.ui.control.Slider
        LoadDataDropDownLabel         matlab.ui.control.Label
        LoadDataDropDown              matlab.ui.control.DropDown
        DeathsButton                  matlab.ui.control.Button
        RstartLabel                   matlab.ui.control.Label
        RendLabel                     matlab.ui.control.Label
        Rlock                         matlab.ui.control.CheckBox
        ReeffectiveReproductionnumberLabel  matlab.ui.control.Label
        RendSpinner                   matlab.ui.control.Spinner
        delayEditFieldLabel           matlab.ui.control.Label
        delayInput                    matlab.ui.control.NumericEditField
        serialintervalEditFieldLabel  matlab.ui.control.Label
        serialintervalEditField       matlab.ui.control.NumericEditField
        IFREditFieldLabel             matlab.ui.control.Label
        IFREditField                  matlab.ui.control.NumericEditField
        varianceEditField_2Label      matlab.ui.control.Label
        varianceInput                 matlab.ui.control.NumericEditField
        fitButton                     matlab.ui.control.Button
        casesPlot                     matlab.ui.control.UIAxes
        RePlot                        matlab.ui.control.UIAxes
        deathsPlot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        timeShift = 0 % Description
        tau % Description
        Rstart % Description
        onset % Description
        slope % Description
        Ndays % Description
        savedModels % Description
        nCases % Description
        Re % Description
        nDay % Description
        n0 % Description
        nDeaths% Description
        nDeathsDist % Description
        country % Description
        Rend % Description
    end
    
    methods (Access = private)
        function results = generateData(app)

            maxCases = 0;
            
            [app.nCases, app.Re] = model(app.nDay, app.n0, app.tau, app.Rstart, app.Rend, app.onset, app.slope);
            if (max(app.nCases) > maxCases) 
                maxCases = max(app.nCases);
            end
            zeroCross = app.zeroCrossing(app.Re-1);
            
            plot(app.casesPlot, app.nDay, app.nCases, '.-b');
            hold(app.casesPlot,'on');
            plot(app.RePlot, app.nDay, app.Re, '.-r');
            hold(app.RePlot,'on');
            for i = 1:length(app.savedModels)
                data = app.savedModels{i};
                if (max(data(2,:)) > maxCases) 
                    maxCases = max(max(data(2,:)));
                end
                plot(app.casesPlot, data(1,:), data(2,:), '.-','Color',[0.5 0.5 0.5]);
                plot(app.RePlot, data(1,:), data(4,:), '.-','Color',[0.5 0.5 0.5]);
            end
            if (~app.LockyscaleCheckBox.Value)
                ylim(app.casesPlot, [0, maxCases*1.1]);
            end

            plot(app.casesPlot, [zeroCross,zeroCross], [0,app.casesPlot.YLim(2)], ':r', "LineWidth", 0.1);
            plot(app.RePlot, [zeroCross,zeroCross], [0,5], ':r',  "LineWidth", 0.1);

%             if (strcmp(class(app.country),'Country'))
%                 app.country.firstCaseDay
%                 range = app.country.firstCaseDay:app.country.firstCaseDay+app.Ndays-1;
%                 plot(app.casesPlot, ...
%                     app.nDay, ...
%                     movmean(100*app.country.deaths(range), 7), ...
%                     '.-') 
%             end
            hold(app.casesPlot,'off');
            hold(app.RePlot,'off');
            
            infectionRatio = '-';
            if (strcmp(class(app.country),'Country'))
                infectionRatio = sprintf('%.0f',100*sum(app.nCases)/app.country.population);
                app.country.population
            end
            app.infectedStatBox.Text = sprintf('      n0: %-3.0f\ninfected: %-.0f (%s%% of population)', app.n0, sum(app.nCases), infectionRatio);
            
        end
        
        function results = simulateDeaths(app, resimulate)

            if (resimulate)
                deathDays = [];
%                 deathPdf = poisspdf(0:39,20);
                deathPdf = lognpdf(0:39,log(20),0.1);
                app.nDeathsDist = zeros(1,app.Ndays);

                for nd = app.nDay
                    if (nd < app.Ndays-40) 
                         app.nDeathsDist(nd:nd+40-1) = app.nDeathsDist(nd:nd+40-1) + 0.01*app.nCases(nd)*deathPdf;
                    end
                end
                
%                 app.nDeaths = zeros(1,app.Ndays);
%                 for nd = app.nDay
%                     if (length(deathDays) < 2000000)
%                         nDeaths = poissrnd(0.01*app.nCases(nd));
%                         deathDays = [deathDays, nd+lognrnd(log(20),0.1,1,nDeaths)];
%                     else
%                         disp('too many deaths');
%                         plot(app.deathsPlot, [0], [0], '.-k')
%                         return
%                     end
%                     
%                 end
%                 app.nDeaths = hist(deathDays,app.nDay);

            end
            
%             plot(app.deathsPlot, app.nDay, movmean(app.nDeaths,1), '.k', app.nDay, app.nDeathsDist, '-k')
            plot(app.deathsPlot, app.nDay, app.nDeathsDist, '.-k')
            hold(app.deathsPlot,"on");
            for i = 1:length(app.savedModels)
                data = app.savedModels{i};
                plot(app.deathsPlot, data(1,:), movmean(data(3,:),1), '.-','Color',[0.5 0.5 0.5]);
            end
            if (strcmp(class(app.country),'Country'))
                ts = floor(app.timeShift);
                range = app.country.firstCaseDay:app.country.firstCaseDay+app.Ndays-1-ts;
                plot(app.deathsPlot, ...
                    app.nDay, ...
                    [zeros(1,ts), movmean(app.country.deaths(range), 7)], ...
                    '.-') 
            end
            hold(app.deathsPlot,"off");
        end
        
        function result = zeroCrossing(app,y)
            try
                zc = find(y(:).*circshift(y(:), [-1 0]) <= 0);
                result = zc(1);
            catch
                result = -1;
            end
        end    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.UITable.RowName = {'Country A'; 'Country B'};
%             app.UITable.Data = [{1 3};{3 3}];
            app.Ndays = 200;
            app.tau = 5;
            app.Rstart = 3;
            app.Rend = 0.7;
            app.onset = 20;
            app.slope = 0.5;
            app.n0 = 1;
            app.savedModels = {};
            app.nDeaths = zeros(1,app.Ndays);
            app.nDeathsDist = zeros(1,app.Ndays);
            app.nCases = zeros(1,app.Ndays);
            app.nDay = (1:app.Ndays);
            app.Re = zeros(1,app.Ndays);
            
            app.casesPlot.PositionConstraint = 'innerposition';
            app.RePlot.PositionConstraint = 'innerposition';
            app.deathsPlot.PositionConstraint = 'innerposition';
            app.RePlot.InnerPosition(1) = app.casesPlot.InnerPosition(1);
            app.RePlot.InnerPosition(3) = app.casesPlot.InnerPosition(3);
            app.deathsPlot.InnerPosition(1) = app.casesPlot.InnerPosition(1);
            app.deathsPlot.InnerPosition(3) = app.casesPlot.InnerPosition(3);

            app.onsetSlider.Position(1) = app.casesPlot.InnerPosition(1);
            app.onsetSlider.Position(3) = app.casesPlot.InnerPosition(3);

            app.RstartSlider.Position(2) = app.RePlot.InnerPosition(2);
            app.RstartSlider.Position(4) = app.RePlot.InnerPosition(4);
            app.RendSlider.Position(2) = app.RePlot.InnerPosition(2);
            app.RendSlider.Position(4) = app.RePlot.InnerPosition(4);

            app.generateData();
            app.simulateDeaths(1);

        end

        % Value changing function: onsetSlider
        function onsetSliderValueChanging(app, event)
%             changingValue = event.Value;
            app.onset = floor(event.Value);
            app.onsetSlider.Value = app.onset;
            set(app.onsetLabel, 'Text', num2str(app.onset));
%             app.infectedStatBox.Text = sprintf('      n0: %-3.0f\ninfected: %-.0f', app.n0, sum(app.nCases));
            app.generateData();
            app.simulateDeaths(1)
        end

        % Value changed function: onsetSlider
        function onsetSliderValueChanged(app, event)
            app.simulateDeaths(1)
        end

        % Value changing function: RstartSlider
        function RstartSliderValueChanging(app, event)
            app.Rstart = event.Value;
            app.RstartLabel.Text = sprintf('%.1f',event.Value);
            if (app.Rlock.Value)
                app.Rend = event.Value;
                app.RendSlider.Value = event.Value;
                app.RendLabel.Text = sprintf('%.1f',event.Value);
            end
            app.generateData();
            app.simulateDeaths(1)
        end

        % Value changed function: RstartSlider
        function RstartSliderValueChanged(app, event)
            value = app.RstartSlider.Value;
            app.simulateDeaths(1)
        end

        % Value changing function: RendSlider
        function RendSliderValueChanging(app, event)
            app.Rend = event.Value;
            app.RendLabel.Text = sprintf('%.1f',event.Value);
            if (app.Rlock.Value)
                app.Rstart = event.Value;
                app.RstartSlider.Value = event.Value;
                app.RstartLabel.Text = sprintf('%.1f',event.Value);
            end
            app.generateData();
            app.simulateDeaths(1)
        end

        % Value changed function: RendSlider
        function RendSliderValueChanged(app, event)
            value = app.RendSlider.Value;
            app.simulateDeaths(1)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.savedModels = [app.savedModels, [app.nDay; app.nCases; app.nDeaths; app.Re]];
            app.generateData();
            app.simulateDeaths(0);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.savedModels = {};
            app.generateData();
            app.simulateDeaths(0);
        end

        % Button pushed function: DeathsButton
        function DeathsButtonPushed(app, event)
            app.simulateDeaths(1)
        end

        % Value changing function: n0Slider
        function n0SliderValueChanging(app, event)
            app.n0 = floor(event.Value);
%             app.infectedStatBox.Text = sprintf('      n0: %-3.0f\ninfected: %-.0f', app.n0, sum(app.nCases));
            app.generateData();
            app.simulateDeaths(1)
        end

        % Value changed function: LockyscaleCheckBox
        function LockyscaleCheckBoxValueChanged(app, event)
            value = app.LockyscaleCheckBox.Value;
            if (value) 
                set(app.casesPlot,'YLimMode','manual');
            else
                set(app.casesPlot,'YLimMode','auto');
            end
            app.generateData();
        end

        % Value changing function: TimeShiftSlider
        function TimeShiftSliderValueChanging(app, event)
            changingValue = event.Value;
            app.timeShift = changingValue;
            app.simulateDeaths(1)
        end

        % Value changed function: LoadDataDropDown
        function LoadDataDropDownValueChanged(app, event)
            value = app.LoadDataDropDown.Value;
            app.country = Country(value,0);
            app.generateData();
        end

        % Value changed function: Rlock
        function RlockValueChanged(app, event)
            value = app.Rlock.Value;
            if (value) 
                app.Rend = app.Rstart;
                app.RendSlider.Value = app.RstartSlider.Value;
                app.RendLabel.Text = app.RstartLabel.Text;
                app.generateData();
            end
        end

        % Button pushed function: fitButton
        function fitButtonPushed(app, event)
            ts = floor(app.timeShift);
            range = app.country.firstCaseDay:app.country.firstCaseDay+app.Ndays-1-ts;
            model = dModel(app.nDay',app.n0,app.tau,app.Rstart,app.Rend,app.onset,app.slope);
            plot(...
                app.nDay, ...
                [zeros(1,ts), movmean(app.country.deaths(range), 7)], ...
                '.-', app.nDay, model,'-'); hold on;

            ft = fittype('dModel(x,a_n0,b_tau,c_Rstart,d_Rend,e_tOnset,f_slope)');
            options = fitoptions(ft);
%             options.Lower =      [0      5       0       0     0       0      0      0];
%             options.Upper =      [0      5       1       inf   inf     10     10     1];
            options.Startpoint = [app.n0,app.tau,app.Rstart,app.Rend,app.onset,app.slope];
            
            f = fit(app.nDay',[zeros(1,ts), movmean(app.country.deaths(range), 7)]', ft, options)
            plot(app.nDay,feval(f,app.nDay')); hold off;
            app.n0 = f.a_n0;
            app.n0Slider.Value = f.a_n0;
            app.Rstart = f.c_Rstart;
            app.RstartSlider.Value = f.c_Rstart;
            app.RstartLabel.Text = sprintf('%.2f',f.c_Rstart);
            app.Rend = f.d_Rend;
            app.RendSlider.Value = f.d_Rend;
            app.RendLabel.Text = sprintf('%.2f',f.d_Rend);
            app.onset = f.e_tOnset;
            app.onsetSlider.Value = f.e_tOnset;
            app.onsetLabel.Text = sprintf('%.2f',f.e_tOnset);
            app.slope = f.f_slope;
            app.generateData();
            app.simulateDeaths(1);
%             plot(f)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeShiftSliderLabel = uilabel(app.UIFigure);
            app.TimeShiftSliderLabel.Text = 'Time Shift';
            app.TimeShiftSlider = uislider(app.UIFigure);
            app.TimeShiftSlider.MajorTicks = [0 100];
            app.TimeShiftSlider.MajorTickLabels = {'0', '100'};
            app.TimeShiftSlider.ValueChangingFcn = createCallbackFcn(app, @TimeShiftSliderValueChanging, true);
            app.TimeShiftSlider.MinorTicks = [];
            app.onsetSlider = uislider(app.UIFigure);
            app.onsetSlider.Limits = [0 200];
            app.onsetSlider.MajorTicks = [];
            app.onsetSlider.MajorTickLabels = {''};
            app.onsetSlider.ValueChangedFcn = createCallbackFcn(app, @onsetSliderValueChanged, true);
            app.onsetSlider.ValueChangingFcn = createCallbackFcn(app, @onsetSliderValueChanging, true);
            app.onsetSlider.MinorTicks = [];
            app.onsetSlider.Value = 20;
            app.LockyscaleCheckBox = uicheckbox(app.UIFigure);
            app.LockyscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockyscaleCheckBoxValueChanged, true);
            app.LockyscaleCheckBox.Text = 'Lock y-scale';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.onsetLabel = uilabel(app.UIFigure);
            app.onsetLabel.Text = '20';
            app.RstartSlider = uislider(app.UIFigure);
            app.RstartSlider.Limits = [0 5];
            app.RstartSlider.MajorTicks = [];
            app.RstartSlider.ValueChangedFcn = createCallbackFcn(app, @RstartSliderValueChanged, true);
            app.RstartSlider.ValueChangingFcn = createCallbackFcn(app, @RstartSliderValueChanging, true);
            app.RstartSlider.MinorTicks = [];
            app.RstartSlider.Value = 3;
            app.RendSlider = uislider(app.UIFigure);
            app.RendSlider.Limits = [0 5];
            app.RendSlider.MajorTicks = [];
            app.RendSlider.ValueChangedFcn = createCallbackFcn(app, @RendSliderValueChanged, true);
            app.RendSlider.ValueChangingFcn = createCallbackFcn(app, @RendSliderValueChanging, true);
            app.RendSlider.MinorTicks = [];
            app.RendSlider.Value = 0.7;
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.infectedStatBox = uilabel(app.UIFigure);
            app.infectedStatBox.Text = {'      n0:'; 'Infected:'; ''};
            app.n0Slider = uislider(app.UIFigure);
            app.n0Slider.Limits = [1 500];
            app.n0Slider.MajorTicks = [];
            app.n0Slider.MajorTickLabels = {''};
            app.n0Slider.ValueChangingFcn = createCallbackFcn(app, @n0SliderValueChanging, true);
            app.n0Slider.MinorTicks = [];
            app.n0Slider.Value = 1;
            app.LoadDataDropDownLabel = uilabel(app.UIFigure);
            app.LoadDataDropDownLabel.Text = 'Load Data';
            app.LoadDataDropDown = uidropdown(app.UIFigure);
            app.LoadDataDropDown.Items = {'Belgium', 'Denmark', 'Sweden', 'Spain', 'Italy'};
            app.LoadDataDropDown.ValueChangedFcn = createCallbackFcn(app, @LoadDataDropDownValueChanged, true);
            app.LoadDataDropDown.Value = 'Sweden';
            app.DeathsButton = uibutton(app.UIFigure, 'push');
            app.DeathsButton.ButtonPushedFcn = createCallbackFcn(app, @DeathsButtonPushed, true);
            app.DeathsButton.Text = 'Deaths';
            app.RstartLabel = uilabel(app.UIFigure);
            app.RstartLabel.Text = '3';
            app.RendLabel = uilabel(app.UIFigure);
            app.RendLabel.Text = '0.7';
            app.Rlock = uicheckbox(app.UIFigure);
            app.Rlock.ValueChangedFcn = createCallbackFcn(app, @RlockValueChanged, true);
            app.Rlock.Text = '';
            app.ReeffectiveReproductionnumberLabel = uilabel(app.UIFigure);
            app.ReeffectiveReproductionnumberLabel.Tooltip = {'Re is supressed by social distancing and protection gear.'};
            app.ReeffectiveReproductionnumberLabel.Text = 'Re (effective Reproduction number)';
            app.RendSpinner = uispinner(app.UIFigure);
            app.delayEditFieldLabel = uilabel(app.UIFigure);
            app.delayEditFieldLabel.Text = 'delay';
            app.delayInput = uieditfield(app.UIFigure, 'numeric');
            app.delayInput.Tooltip = {'Infection Fatality Rate. The fraction of all infected that dies.'};
            app.delayInput.Value = 20;
            app.serialintervalEditFieldLabel = uilabel(app.UIFigure);
            app.serialintervalEditFieldLabel.Text = 'serial interval';
            app.serialintervalEditField = uieditfield(app.UIFigure, 'numeric');
            app.serialintervalEditField.Tooltip = {'Infection Fatality Rate. The fraction of all infected that dies.'};
            app.serialintervalEditField.Value = 5;
            app.IFREditFieldLabel = uilabel(app.UIFigure);
            app.IFREditFieldLabel.Text = 'IFR';
            app.IFREditField = uieditfield(app.UIFigure, 'numeric');
            app.IFREditField.Tooltip = {'Infection Fatality Rate. The fraction of all infected that dies.'};
            app.IFREditField.Value = 0.01;
            app.varianceEditField_2Label = uilabel(app.UIFigure);
            app.varianceEditField_2Label.Text = 'variance';
            app.varianceInput = uieditfield(app.UIFigure, 'numeric');
            app.varianceInput.Tooltip = {'Infection Fatality Rate. The fraction of all infected that dies.'};
            app.varianceInput.Value = 20;
            app.fitButton = uibutton(app.UIFigure, 'push');
            app.fitButton.ButtonPushedFcn = createCallbackFcn(app, @fitButtonPushed, true);
            app.fitButton.Text = 'Fit';
            app.casesPlot = uiaxes(app.UIFigure);
            zlabel(app.casesPlot, 'Z')
            app.casesPlot.XLim = [0 200];
            app.casesPlot.XTick = [20 40 60 80 100 120 140 160 180];
            app.RePlot = uiaxes(app.UIFigure);
            zlabel(app.RePlot, 'Z')
            app.RePlot.XLim = [0 200];
            app.RePlot.YLim = [0.1 5];
            app.RePlot.XTick = [];
            app.RePlot.YTick = [1 2 3 4 5];
            app.deathsPlot = uiaxes(app.UIFigure);
            xlabel(app.deathsPlot, 'days')
            zlabel(app.deathsPlot, 'Z')
            app.deathsPlot.XLim = [0 200];
            app.deathsPlot.XTick = [20 40 60 80 100 120 140 160 180];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end